classdef V2V_VLC_MatrixHeadlights < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ScenarioConfigurationLabel     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        dminEditFieldLabel_8           matlab.ui.control.Label
        RadiusField                    matlab.ui.control.NumericEditField
        dminEditFieldLabel_9           matlab.ui.control.Label
        DField                         matlab.ui.control.NumericEditField
        SwitchEscenario                matlab.ui.control.Switch
        SwitchEscenario2               matlab.ui.control.Switch
        VehicleConfigurationLabel      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        VehicleconfigurationButton     matlab.ui.control.Button
        dminEditField                  matlab.ui.control.NumericEditField
        dminEditFieldLabel_7           matlab.ui.control.Label
        dmaxEditFieldLabel             matlab.ui.control.Label
        dmaxEditField                  matlab.ui.control.NumericEditField
        stepsizeEditFieldLabel         matlab.ui.control.Label
        stepsizeEditField              matlab.ui.control.NumericEditField
        SimulationConfigurationLabel   matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        ReceiverandnoiseconfigurationLabel  matlab.ui.control.Label
        HeadlampsConfigurationHLLabel  matlab.ui.control.Label
        FilasSpinner                   matlab.ui.control.Spinner
        FilasSpinnerLabel              matlab.ui.control.Label
        ColumnasSpinner                matlab.ui.control.Spinner
        ColumnasSpinnerLabel           matlab.ui.control.Label
        SepHorizontSpinner             matlab.ui.control.Spinner
        SepHorizontSpinnerLabel        matlab.ui.control.Label
        SepverticalSpinnerLabel        matlab.ui.control.Label
        SepverticalSpinner             matlab.ui.control.Spinner
        dminEditFieldLabel_3           matlab.ui.control.Label
        powerField                     matlab.ui.control.NumericEditField
        dmaxEditFieldLabel_3           matlab.ui.control.Label
        EGCSwitchLabel                 matlab.ui.control.Label
        HPSAfield                      matlab.ui.control.NumericEditField
        EGCSwitch                      matlab.ui.control.Switch
        MRCSwitchLabel                 matlab.ui.control.Label
        MRCSwitch                      matlab.ui.control.Switch
        SBSwitchLabel                  matlab.ui.control.Label
        SBSwitch                       matlab.ui.control.Switch
        ConfigurepowerofeachtxButton   matlab.ui.control.Button
        ConfigureorientationofeachtxButton  matlab.ui.control.Button
        RxconfigurationButton          matlab.ui.control.Button
        CombinationmethodsLabel        matlab.ui.control.Label
        ConfigureHPSAindividuallyButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        SimulationOutputLabel          matlab.ui.control.Label
        IlluminationdiagramButton      matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        %Vehicle Leader
        Lv_leader;
        DF_leader
        DB_leader;
        HF_leader;
        HB_leader;
        H_leader;
        %Vehicle follower
        Lv_follower;
        DF_follower;
        DB_follower;
        HF_follower;
        HB_follower;
        H_follower;
        
        m;
        P;
        FOV;
        A;
        Ad;
        g;
        DTX; % Data transmitters
        DRX; % Data receivers    
        HPSA;
        sepVert;
        sepHorz;
        
        R; % radio de curva [m]
        dmin;   % distancia mínima
        dmax;  % distancia máxima
        dd;
        n_Filas_Tx;  %app.FilasSpinner.Value;         %%% Añadir opción modificación más cómoda luego !!!
        n_Col_Tx;  %app.ColumnasSpinner.Value;
        MatPot;  %Matriz de potencia, sustituira par(2) en ParamTx
        B;     % ancho de banda
        Ibg1;
        Ibg2;
        D;
        EGC_switch;
        MRC_switch;
        SB_switch;
        
        lane_aux;
        road_aux;
        SNRegc;
        SNR_EGCdB;
        SNRmrc;
        SNR_MRCdB;
        SNRsb;
        SNR_SBdB;
        
        boolM;
        boolP; 
        boolO;
        DTX_solo_M;
        DTX_solo_potencia;
        DTX_solo_orientacion;
        
        SNR_EGC_simple;
        SNR_MRC_simple;
        SNR_SB_simple;
    end
    
    properties (Access = private)
        DialogAppPower; % Auxiliary app
    end
    
    methods (Access = public)
        
        
        function updateMatPot(app,pot,bool) 
            app.DTX_solo_potencia = pot;
            app.boolP = bool;
        end
        
        function updateM(app,m,bool) 
            app.DTX_solo_M = m;
            app.boolM = bool;
        end
    end
    
    methods (Access = public)
        function updateMatOrientacion(app,matriz,bool)
            app.DTX_solo_orientacion = matriz;
            app.boolO = bool;     
        end
    end
    
    methods (Access = public)
        
        function updateVehicleParameters(app,matrixLead,matrixFollow)
        app.Lv_leader = matrixLead(1,1);
        app.DF_leader = matrixLead(1,2);
        app.DB_leader = matrixLead(1,3);
        app.HF_leader = matrixLead(1,4);
        app.HB_leader = matrixLead(1,5);
        app.H_leader = matrixLead(1,6);
        %Vehicle follower
        app.Lv_follower = matrixFollow(1,1);
        app.DF_follower = matrixFollow(1,2);
        app.DB_follower = matrixFollow(1,3);
        app.HF_follower = matrixFollow(1,4);
        app.HB_follower = matrixFollow(1,5);
        app.H_follower = matrixFollow(1,6);
       
        
        app.boolO = false;
        app.boolP = false;
        end
    end
    
    methods (Access = public)
        
        function updateRxNoise(app,matrixRx,matrixNoise)
            app.Ad = matrixRx(1,1);
            app.g = matrixRx(1,2);
            app.FOV = matrixRx(1,3)*pi/180;
            app.B = matrixNoise(1,1);
            app.Ibg1 = matrixNoise(1,2);
            app.Ibg2 = matrixNoise(1,3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Vehicle Leader
        app.Lv_leader = 4.6;
        app.DF_leader = 1.3;
        app.DB_leader = 1.1;
        app.HF_leader = 0.5;
        app.HB_leader = 0.5;
        app.H_leader = 0.5;
        %Vehicle follower
        app.Lv_follower = 4.6;
        app.DF_follower = 1.3;
        app.DB_follower = 1.1;
        app.HF_follower = 0.5;
        app.HB_follower = 0.5;
        app.H_follower = 0.5;
         
            
        app.dmin = app.dminEditField.Value;
        app.dd = app.stepsizeEditField.Value;
        app.dmax = app.dmaxEditField.Value;
        app.R = app.RadiusField.Value;
        app.D = app.DField.Value;
           
        app.P = app.powerField.Value;                       % potencia [W]
        app.HPSA= app.HPSAfield.Value*pi/180;           % Semiángulo de potencia mitad [rad].
        app.m=-log(2)/log(cos(app.HPSA));  % orden Lambertiano
        app.B = 5e+06;
        app.Ibg1 = 740 * 10^-6;
        app.Ibg2 = 5100 * 10^-6;

        app.Ad = 5*10^-05;                % Area de detección [m^2] (sin concentrador)
        app.FOV = 55*pi/180;             % Campo visión [rad] (semiángulo).
        app.g = 1;

        no=1.5;                    % índice de refracción concentrador
        %app.g = no^2/sin(app.FOV)^2;         % ganancia del concetrador
        app.A = app.Ad*app.g;                    % área equivalente [m^2]

        app.n_Filas_Tx = app.FilasSpinner.Value;
        app.n_Col_Tx = app.ColumnasSpinner.Value;
        
        app.MRC_switch = 'On';
        app.EGC_switch = 'On';
        app.SB_switch = 'On';
        app.lane_aux = 'Alligned';
        app.road_aux = 'Straight road';
        
        app.boolP = false;
        app.boolO = false;
        app.boolM = false;
        PlotButtonPushed(app);
        message = sprintf("First adjust the window size\n" + ...
            "Select scenario, configuration and press plot\n" + ...
            "After plotting you can  configure power, orientation or visualize the illumination diagram");
        uialert(app.UIFigure,message,"INFO",'Icon',"info");
        end

        % Callback function
        function FilasSpinnerValueChanged(app, event)
            app.n_Filas_Tx = app.FilasSpinner.Value;
        end

        % Callback function
        function ColumnasSpinnerValueChanged(app, event)
            app.n_Col_Tx = app.ColumnasSpinner.Value;
        end

        % Callback function
        function SepHorizontSpinnerValueChanged(app, event)
            value = app.SepHorizontSpinner.Value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        %cla(app.UIAxes,'reset');
        %cla(app.UIAxes_3,'reset');
        P=1;                       
        HPSA= 20*pi/180;           
        m=-log(2)/log(cos(HPSA));  
        Ad=5*10^-5;                
        FOV=55*pi/180;             
        g=1;
        A=Ad*g;                    
            
        ParamTxRx=[app.m,app.P,app.FOV,app.A,app.FilasSpinner.Value,app.ColumnasSpinner.Value,app.SepverticalSpinner.Value,app.SepHorizontSpinner.Value];
        ParamTxRx2=[m,P,FOV,A];
        app.MatPot = ones(app.FilasSpinner.Value*app.ColumnasSpinner.Value,1);
        app.MatPot = (app.P/(app.FilasSpinner.Value*app.ColumnasSpinner.Value))*app.MatPot;
            
        pesosEGC=[0.5 0.5]; % pesos EGC
        
        Hegc=[];Hmrc=[];Hsb=[];
        IN2=[];IN2egc=[];IN2mrc=[];IN2sb=[];
        Hegc2=[];Hmrc2=[];Hsb2=[];
        IN2egc2=[];IN2mrc2=[];IN2sb2=[];
        
        paramVeh1 = [app.Lv_leader,app.DF_leader,app.DB_leader,app.HF_leader,app.HB_leader,app.H_leader];
        paramVeh2 = [app.Lv_follower,app.DF_follower,app.DB_follower,app.HF_follower,app.HB_follower,app.H_follower];

        %disp(ParamTxRx);
     if strcmp(app.lane_aux,'Alligned') 
        for d=app.dmin:app.dd:app.dmax
            if strcmp(app.road_aux,'Straight road')
                [app.DTX,app.DRX]=GeneraEscenario1recta(d,ParamTxRx,app.MatPot,paramVeh1,paramVeh2);      %%%% N_FILAS Y N_COLUMNAS
            [DTX2,DRX2]=GenEsc1recta(d,ParamTxRx2);
            elseif strcmp(app.road_aux,'Curved road')
                [app.DTX,app.DRX]=GeneraEscenario1(d,app.R,ParamTxRx,app.MatPot,paramVeh1,paramVeh2);      %%%% N_FILAS Y N_COLUMNAS
            [DTX2,DRX2]=GenEsc1(d,app.R,ParamTxRx2);
            end
            
            if app.boolP == true
                app.DTX(:,8) = app.DTX_solo_potencia(:,1);
            end
            if app.boolO == true
                app.DTX(:,4) = app.DTX_solo_orientacion(:,1);
                app.DTX(:,5) = app.DTX_solo_orientacion(:,2);
            end
            if app.boolM == true
                app.DTX(:,1) = app.DTX_solo_M(:,1);
            end
                [Hdc,tp]=CalculaParametros(app.DTX,app.DRX);     
                [Hdc2,tp2]=CalculaParametros(DTX2,DRX2);
%                 disp(app.DTX);
%                 disp(app.DRX);
                
            % meto provisionalmente aquí el ruido
            % ruido ambiental
             qe=1.6*10^-19;  % carga del electron
             %Ibg1=740*10^-6;   % Sol indirecto esto debería ser variable
             %Ibg2=5100*10^-6;   % Sol directo esto debería ser variable
             Ibg= app.Ibg1*(0.5/0.8);%la corrijo por diferentes areas 0.5/0.8   
             
             I2=0.562;
             nshot=2*qe*Ibg*I2*app.B; % ruido shot
             nint=0;              % ruido interno
             in=nshot+nint;       % ruido total
             IN2=[IN2 in];
             int=sum(Hdc);
             int2=sum(Hdc2);

        %%%%%%%%%%%%%%
        
            pesosMRC=[int(1)/in int(2)/in]; % pesos wj combinación MRC
            pesosMRC2=[int2(1)/in int2(2)/in]; % pesos wj combinación MRC
            pesosMRC2=pesosMRC2/sum(pesosMRC2);

            [peso,posi]=max(pesosMRC);
            pesosSB=[0 0];       
            pesosSB(posi)=1;             % pesos wj combinación SB
            pesosMRC=pesosMRC/sum(pesosMRC);  

            IN2egc=[IN2egc in*sum(pesosEGC.^2)];
            IN2mrc=[IN2mrc in*sum(pesosMRC.^2)];
            IN2sb=[IN2sb in*sum(pesosSB.^2)];  
        H1 = []; H2 = []; H3 = []; H4 = [];
        Size = app.FilasSpinner.Value*app.ColumnasSpinner.Value;
        
        H1 = Hdc(1,1:Size).*app.DTX(1:Size,8);
        H2 = Hdc(1,Size+1:2*Size).*app.DTX(Size+1:2*Size,8);
        H3 = Hdc(2,1:Size).*app.DTX(1:Size,8);
        H4 = Hdc(2,Size+1:2*Size).*app.DTX(Size+1:2*Size,8);
        
        H_dc = (1/(Size)).*[sum(H1,'all') sum(H2,'all'); sum(H3,'all') sum(H4,'all')];
        H_dc_sb = [sum(H1,'all') sum(H2,'all'); sum(H3,'all') sum(H4,'all')];


            Hegc=[Hegc sum(H_dc'*pesosEGC.')];
            Hmrc=[Hmrc sum(H_dc'*pesosMRC.')];    
            Hsb=[Hsb sum(H_dc'*pesosSB.')];
            
  
            Hegc2=[Hegc2 sum(Hdc2*pesosEGC.')];
            Hmrc2=[Hmrc2 sum(Hdc2*pesosMRC2.')];    
            Hsb2=[Hsb2 sum(Hdc2*pesosSB.')];
            IN2egc2=[IN2egc2 in*sum(pesosEGC.^2)];
            IN2mrc2=[IN2mrc2 in*sum(pesosMRC2.^2)];
            IN2sb2=[IN2sb2 in*sum(pesosSB.^2)];

        
        %%%%%%%%%%%%%%%%
        end
               
        %xd=xd-4.6;
            respons=0.5;
            if strcmp(app.road_aux,'Straight road')
                    app.SNRegc=((respons*Hegc).^2)./IN2egc;
                    app.SNR_EGCdB=10*log10(app.SNRegc);
                    app.SNRmrc=((respons*Hmrc).^2)./IN2mrc;
                    app.SNR_MRCdB=10*log10(app.SNRmrc);
                    app.SNRsb=((respons*Hsb).^2)./IN2sb;
                    app.SNR_SBdB=10*log10(app.SNRsb);
            SNRegc2=((respons*Hegc2*DTX2(1,8)).^2)./IN2egc2;
            SNR_EGCdB2=10*log10(SNRegc2);
            SNRmrc2=((respons*Hmrc2*DTX2(1,8)).^2)./IN2mrc2;
            SNR_MRCdB2=10*log10(SNRmrc2);
            SNRsb2=((respons*Hsb2*DTX2(1,8)).^2)./IN2sb2;
            SNR_SBdB2=10*log10(SNRsb2);

                elseif strcmp(app.road_aux,'Curved road')
                    app.SNRegc=((respons*Hegc).^2)./IN2egc;
                    app.SNR_EGCdB=10*log10(app.SNRegc);
                    app.SNRmrc=((respons*Hmrc).^2)./IN2mrc;
                    app.SNR_MRCdB=10*log10(app.SNRmrc);
                    app.SNRsb=((respons*Hsb).^2)./IN2sb;
                    app.SNR_SBdB=10*log10(app.SNRsb);
            SNRegc2=((respons*Hegc2*DTX2(1,8)).^2)./IN2egc2;
            SNR_EGCdB2=10*log10(SNRegc2);
            SNRmrc2=((respons*Hmrc2*DTX2(1,8)).^2)./IN2mrc2;
            SNR_MRCdB2=10*log10(SNRmrc2);
            SNRsb2=((respons*Hsb2*DTX2(1,8)).^2)./IN2sb2;
            SNR_SBdB2=10*log10(SNRsb2);
                 end
            
            xd=[app.dmin:(app.dmax-app.dmin)/size(app.SNRegc,2):app.dmax-1/size(app.SNRegc,2)];

            cla(app.UIAxes_3,'reset');
            app.UIAxes_3.Title.String = "Signal to Noise Ratio";
            hold(app.UIAxes_3,'on');
            if strcmp(app.SB_switch,'On') 
                plot(app.UIAxes_3,xd,app.SNR_SBdB,'r');
                plot(app.UIAxes_3,xd,SNR_SBdB2,'r--');
            end
            if strcmp(app.EGC_switch,'On')
                plot(app.UIAxes_3,xd,app.SNR_EGCdB,'b');
                plot(app.UIAxes_3,xd,SNR_EGCdB2,'b--');
            end
            if strcmp(app.MRC_switch,'On')
                plot(app.UIAxes_3,xd,app.SNR_MRCdB,'k');
                plot(app.UIAxes_3,xd,SNR_MRCdB2,'k--');
            end
            legend(app.UIAxes_3,"SB","SB 1 emisor/faro","MRC","MRC 1 emisor/faro","EGC","EGC 1 emisor/faro");

            hold(app.UIAxes_3,'off');
            
            cla(app.UIAxes,'reset');
            app.UIAxes.Title.String = 'Ganancia en continua del canal [dB]';
            hold(app.UIAxes,'on');
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes_3.XGrid = 'on';
                app.UIAxes_3.YGrid = 'on';
            if strcmp(app.SB_switch,'On') 
                plot(app.UIAxes,xd,10*log10(Hsb),'Color','r');
                plot(app.UIAxes,xd,10*log10(Hsb2),'r-.');
            end
            if strcmp(app.EGC_switch,'On')
                plot(app.UIAxes,xd,10*log10(Hegc),'Color','b');
                plot(app.UIAxes,xd,10*log10(Hegc2),'b-.');
            end
            if strcmp(app.MRC_switch,'On')
                plot(app.UIAxes,xd,10*log10(Hmrc),'Color','k');
                plot(app.UIAxes,xd,10*log10(Hmrc2),'k-.');
            end

            legend(app.UIAxes,"SB","SB 1 emisor/faro","MRC","MRC 1 emisor/faro","EGC","EGC 1 emisor/faro");
          
            hold(app.UIAxes,'off');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     elseif strcmp(app.lane_aux,'Parallel')

         R2 = app.R + app.D;
        
        for d=app.dmin:app.dd:app.dmax
            if strcmp(app.road_aux,'Straight road')
                [app.DTX,app.DRX]=GeneraEscenario2recta(d,app.D,ParamTxRx,app.MatPot,paramVeh1,paramVeh2);      %%%% N_FILAS Y N_COLUMNAS
                [DTX2,DRX2]=GenEsc2recta(d,app.D,ParamTxRx2);
            elseif strcmp(app.road_aux,'Curved road')
                [app.DTX,app.DRX]=GeneraEscenario2(d,app.R,R2,ParamTxRx,app.MatPot,paramVeh1,paramVeh2);      %%%% N_FILAS Y N_COLUMNAS
                [DTX2,DRX2]=GenEsc2(d,app.R,R2,ParamTxRx2);
            end
            
            if app.boolP == true
                app.DTX(:,8) = app.DTX_solo_potencia(:,1);
            end
            if app.boolO == true
                app.DTX(:,4) = app.DTX_solo_orientacion(:,1);
                app.DTX(:,5) = app.DTX_solo_orientacion(:,2);
            end
            if app.boolM == true
                app.DTX(:,1) = app.DTX_solo_M(:,1);
            end
            
            [Hdc,tp]=CalculaParametros(app.DTX,app.DRX);            
            [Hdc2,tp2]=CalculaParametros(DTX2,DRX2);
            % meto provisionalmente aquí el ruido
            % ruido ambiental
             qe=1.6*10^-19;  % carga del electron
             %Ibg1=740*10^-6;   % Sol indirecto esto debería ser variable
             %Ibg2=5100*10^-6;   % Sol directo esto debería ser variable
             Ibg= app.Ibg1*(0.5/0.8);%la corrijo por diferentes areas 0.5/0.8   
             
             I2=0.562;
             nshot=2*qe*Ibg*I2*app.B; % ruido shot
             nint=0;              % ruido interno
             in=nshot+nint;       % ruido total
             IN2=[IN2 in];
             int=sum(Hdc);
             int2=sum(Hdc2);

        %%%%%%%%%%%%%%
     
            pesosMRC=[int(1)/in int(2)/in]; % pesos wj combinación MRC
            [peso,posi]=max(pesosMRC);
            pesosSB=[0 0];       
            pesosSB(posi)=1;             % pesos wj combinación SB
            pesosMRC=pesosMRC/sum(pesosMRC);
            pesosMRC2=[int2(1)/in int2(2)/in]; % pesos wj combinación MRC
            pesosMRC2=pesosMRC2/sum(pesosMRC2);
                    H1 = []; H2 = []; H3 = []; H4 = [];
        Size = app.FilasSpinner.Value*app.ColumnasSpinner.Value;
        
        H1 = Hdc(1,1:Size).*app.DTX(1:Size,8);
        H2 = Hdc(1,Size+1:2*Size).*app.DTX(Size+1:2*Size,8);
        H3 = Hdc(2,1:Size).*app.DTX(1:Size,8);
        H4 = Hdc(2,Size+1:2*Size).*app.DTX(Size+1:2*Size,8);
        
        H_dc = (1/(Size)).*[sum(H1,'all') sum(H2,'all'); sum(H3,'all') sum(H4,'all')];
        H_dc_sb = [sum(H1,'all') sum(H2,'all'); sum(H3,'all') sum(H4,'all')];


            Hegc=[Hegc sum(H_dc'*pesosEGC.')];
            Hmrc=[Hmrc sum(H_dc'*pesosMRC2.')];%%%%%%2    
            Hsb=[Hsb sum(H_dc'*pesosSB.')];

            IN2egc=[IN2egc in*sum(pesosEGC.^2)];
            IN2mrc=[IN2mrc in*sum(pesosMRC2.^2)];%%%%%%2
            IN2sb=[IN2sb in*sum(pesosSB.^2)]; 
            
            Hegc2=[Hegc2 sum(Hdc2*pesosEGC.')];
            Hmrc2=[Hmrc2 sum(Hdc2*pesosMRC2.')];    
            Hsb2=[Hsb2 sum(Hdc2*pesosSB.')];
            IN2egc2=[IN2egc2 in*sum(pesosEGC.^2)];
            IN2mrc2=[IN2mrc2 in*sum(pesosMRC2.^2)];
            IN2sb2=[IN2sb2 in*sum(pesosSB.^2)];


        %%%%%%%%%%%%%%%%
        end
               
        %xd=xd-4.6;
            respons=0.5;
            if strcmp(app.road_aux,'Straight road')
                    app.SNRegc=((respons*Hegc).^2)./IN2egc;
                    app.SNR_EGCdB=10*log10(app.SNRegc);
                    app.SNRmrc=((respons*Hmrc).^2)./IN2mrc;
                    app.SNR_MRCdB=10*log10(app.SNRmrc);
                    app.SNRsb=((respons*Hsb).^2)./IN2sb;
                    app.SNR_SBdB=10*log10(app.SNRsb);
            SNRegc2=((respons*Hegc2*DTX2(1,8)).^2)./IN2egc2;
            SNR_EGCdB2=10*log10(SNRegc2);
            SNRmrc2=((respons*Hmrc2*DTX2(1,8)).^2)./IN2mrc2;
            SNR_MRCdB2=10*log10(SNRmrc2);
            SNRsb2=((respons*Hsb2*DTX2(1,8)).^2)./IN2sb2;
            SNR_SBdB2=10*log10(SNRsb2);
%                     app.SNRegc=((respons*Hegc).^2)./IN2egc;
%                     app.SNR_EGCdB=10*log10(app.SNRegc);
%                     app.SNRmrc=((respons*Hmrc).^2)./IN2mrc;
%                     app.SNR_MRCdB=10*log10(app.SNRmrc);
%                     app.SNRsb=((respons*Hsb).^2)./IN2sb;
%                     app.SNR_SBdB=10*log10(app.SNRsb);
%             SNRegc2=((respons*Hegc2*DTX2(1,8)).^2)./IN2egc2;
%             SNR_EGCdB2=10*log10(SNRegc2);
%             SNRmrc2=((respons*Hmrc2*DTX2(1,8)).^2)./IN2mrc2;
%             SNR_MRCdB2=10*log10(SNRmrc2);
%             SNRsb2=((respons*Hsb2*DTX2(1,8)).^2)./IN2sb2;
%             SNR_SBdB2=10*log10(SNRsb2);
                elseif strcmp(app.road_aux,'Curved road')
                    app.SNRegc=((respons*Hegc).^2)./IN2egc;
                    app.SNR_EGCdB=10*log10(app.SNRegc);
                    app.SNRmrc=((respons*Hmrc).^2)./IN2mrc;
                    app.SNR_MRCdB=10*log10(app.SNRmrc);
                    app.SNRsb=((respons*Hsb).^2)./IN2sb;
                    app.SNR_SBdB=10*log10(app.SNRsb); 
            SNRegc2=((respons*Hegc2*DTX2(1,8)).^2)./IN2egc2;
            SNR_EGCdB2=10*log10(SNRegc2);
            SNRmrc2=((respons*Hmrc2*DTX2(1,8)).^2)./IN2mrc2;
            SNR_MRCdB2=10*log10(SNRmrc2);
            SNRsb2=((respons*Hsb2*DTX2(1,8)).^2)./IN2sb2;
            SNR_SBdB2=10*log10(SNRsb2);
                 end

            xd=[app.dmin:(app.dmax-app.dmin)/size(app.SNRegc,2):app.dmax-1/size(app.SNRegc,2)];
                        
            cla(app.UIAxes_3,'reset');
            app.UIAxes_3.Title.String = "Signal to Noise Ratio";
            hold(app.UIAxes_3,'on');
            if strcmp(app.SB_switch,'On') 
                plot(app.UIAxes_3,xd,app.SNR_SBdB,'r');
                plot(app.UIAxes_3,xd,SNR_SBdB2,'r--');
            end
            if strcmp(app.EGC_switch,'On')
                plot(app.UIAxes_3,xd,app.SNR_EGCdB,'b');
                plot(app.UIAxes_3,xd,SNR_EGCdB2,'b--');
            end
            if strcmp(app.MRC_switch,'On')
                plot(app.UIAxes_3,xd,app.SNR_MRCdB,'k');
                plot(app.UIAxes_3,xd,SNR_MRCdB2,'k--');
            end
            legend(app.UIAxes_3,"SB","SB 1 emisor/faro","MRC","MRC 1 emisor/faro","EGC","EGC 1 emisor/faro");

            hold(app.UIAxes_3,'off');
            
            cla(app.UIAxes,'reset');
            app.UIAxes.Title.String = 'Ganancia en continua del canal [dB]';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes_3.XGrid = 'on';
            app.UIAxes_3.YGrid = 'on';
            hold(app.UIAxes,'on');
            if strcmp(app.SB_switch,'On') 
                plot(app.UIAxes,xd,10*log10(Hsb),'r');
                plot(app.UIAxes,xd,10*log10(Hsb2),'r-.');
            end
            if strcmp(app.EGC_switch,'On')
                plot(app.UIAxes,xd,10*log10(Hegc),'b');
                plot(app.UIAxes,xd,10*log10(Hegc2),'b-.');
            end
            if strcmp(app.MRC_switch,'On')
                plot(app.UIAxes,xd,10*log10(Hmrc),'k');
                plot(app.UIAxes,xd,10*log10(Hmrc2),'k-.');
            end
            legend(app.UIAxes,"SB","SB 1 emisor/faro","MRC","MRC 1 emisor/faro","EGC","EGC 1 emisor/faro");

            hold(app.UIAxes,'off');
         end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogAppPower)
            delete(app)
        end

        % Value changed function: SepverticalSpinner
        function SepverticalSpinnerValueChanged(app, event)
            value = app.SepverticalSpinner.Value;
            app.boolP = false;
            app.boolO = false;
        end

        % Value changed function: dminEditField
        function dminEditFieldValueChanged(app, event)
            value = app.dminEditField.Value;
            app.dmin = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Value changed function: dmaxEditField
        function dmaxEditFieldValueChanged(app, event)
            value = app.dmaxEditField.Value;
            app.dmax = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Value changed function: stepsizeEditField
        function stepsizeEditFieldValueChanged(app, event)
            value = app.stepsizeEditField.Value;
            app.dd = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Callback function
        function bandwitchFieldValueChanged(app, event)
            value = app.bandwitchField.Value;
            app.B = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Callback function
        function Ibg1FieldValueChanged(app, event)
            value = app.Ibg1Field.Value;
            app.Ibg1 = value * 10^(-6);
        end

        % Callback function
        function Ibg2FieldValueChanged(app, event)
            value = app.Ibg2Field.Value;
            app.Ibg2 = value * 10^(-6);
        end

        % Value changed function: powerField
        function powerFieldValueChanged(app, event)
            value = app.powerField.Value;
            app.P = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Value changed function: HPSAfield
        function HPSAfieldValueChanged(app, event)
            value = app.HPSAfield.Value;
            app.HPSA = value *pi/180;
        end

        % Callback function
        function DetectionAreaFieldValueChanged(app, event)
            value = app.DetectionAreaField.Value;
            app.Ad = value;
        end

        % Callback function
        function OpticalGainFieldValueChanged(app, event)
            value = app.OpticalGainField.Value;

        end

        % Callback function
        function FOVFieldValueChanged(app, event)
            value = app.FOVField.Value;
            app.FOV = value*pi/180;
        end

        % Value changed function: RadiusField
        function RadiusFieldValueChanged(app, event)
            value = app.RadiusField.Value;
            app.R = value;
        end

        % Value changed function: DField
        function DFieldValueChanged(app, event)
            value = app.DField.Value;
            app.D = value;
            app.boolP = false;
            app.boolO = false;
           app.boolM = false;
        end

        % Value changed function: EGCSwitch
        function EGCSwitchValueChanged(app, event)
            value = app.EGCSwitch.Value;
            app.EGC_switch = value;
        end

        % Value changed function: MRCSwitch
        function MRCSwitchValueChanged(app, event)
            value = app.MRCSwitch.Value;
            app.MRC_switch = value;
        end

        % Value changed function: SBSwitch
        function SBSwitchValueChanged(app, event)
            value = app.SBSwitch.Value;
            app.SB_switch = value;
        end

        % Value changed function: SwitchEscenario
        function SwitchEscenarioValueChanged(app, event)
            value = app.SwitchEscenario.Value;
            app.lane_aux = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Value changed function: SwitchEscenario2
        function SwitchEscenario2ValueChanged(app, event)
            value = app.SwitchEscenario2.Value;
            app.road_aux = value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Button pushed function: ConfigurepowerofeachtxButton
        function ConfigurepowerofeachtxButtonPushed(app, event)
            if app.boolP == true
                matrizPotencias = app.DTX_solo_potencia;
            else
                matrizPotencias = app.DTX(:,8);
            end
            app.DialogAppPower = app2(app,matrizPotencias,app.boolP,app.FilasSpinner.Value,app.ColumnasSpinner.Value);
        end

        % Value changed function: FilasSpinner
        function FilasSpinnerValueChanged2(app, event)
            value = app.FilasSpinner.Value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Value changed function: ColumnasSpinner
        function ColumnasSpinnerValueChanged2(app, event)
            value = app.ColumnasSpinner.Value;
            app.boolP = false;
            app.boolO = false;
            app.boolM = false;
        end

        % Button pushed function: ConfigureorientationofeachtxButton
        function ConfigureorientationofeachtxButtonPushed(app, event)
            if app.boolO == true
                matrizTheta = app.DTX(:,4:6);
            else
                matrizTheta = app.DTX(:,4:6);
            end
            app.DialogAppPower = app3(app,matrizTheta,app.boolO,app.FilasSpinner.Value,app.ColumnasSpinner.Value);
        end

        % Button pushed function: VehicleconfigurationButton
        function VehicleconfigurationButtonPushed(app, event)
            matrixLeader = [app.Lv_leader,app.DF_leader,app.DB_leader,app.HF_leader,app.HB_leader,app.H_leader];
            matrixFollower = [app.Lv_follower,app.DF_follower,app.DB_follower,app.HF_follower,app.HB_follower,app.H_follower];
            disp(matrixLeader);
            disp(matrixFollower);
            app.DialogAppPower = app14(app,matrixLeader,matrixFollower);
            
        end

        % Button pushed function: RxconfigurationButton
        function RxconfigurationButtonPushed(app, event)
            matrixRx = [app.Ad,app.g,app.FOV*180/pi];
            matrixNoise = [app.B,app.Ibg1,app.Ibg2];
            app.DialogAppPower = app5(app,matrixRx,matrixNoise);
        end

        % Button pushed function: IlluminationdiagramButton
        function IlluminationdiagramButtonPushed(app, event)

            app.DialogAppPower = app6(app,app.DTX,app.DRX,app.FilasSpinner.Value,app.ColumnasSpinner.Value);
        end

        % Button pushed function: ConfigureHPSAindividuallyButton
        function ConfigureHPSAindividuallyButtonPushed(app, event)
            if app.boolM == true
                matrizM = app.DTX(:,1);
            else
                matrizM = app.DTX(:,1);
            end
            app.DialogAppPower = appM(app,matrizM,app.boolM,app.FilasSpinner.Value,app.ColumnasSpinner.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {722, 722, 722};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {722, 722};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x', 437};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ScenarioConfigurationLabel = uilabel(app.LeftPanel);
            app.ScenarioConfigurationLabel.Text = 'Scenario Configuration';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Screen Shot 2021-07-07 at 17.15.52.png';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'Screen Shot 2021-07-07 at 17.20.01.png';
            app.dminEditFieldLabel_8 = uilabel(app.LeftPanel);
            app.dminEditFieldLabel_8.Text = 'Curve radius (R)';
            app.RadiusField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusField.Limits = [0 Inf];
            app.RadiusField.ValueDisplayFormat = '%11.4g [m]';
            app.RadiusField.ValueChangedFcn = createCallbackFcn(app, @RadiusFieldValueChanged, true);
            app.RadiusField.Value = 50;
            app.dminEditFieldLabel_9 = uilabel(app.LeftPanel);
            app.dminEditFieldLabel_9.Text = 'Lane distance (D)';
            app.DField = uieditfield(app.LeftPanel, 'numeric');
            app.DField.Limits = [0 Inf];
            app.DField.ValueDisplayFormat = '%11.4g [m]';
            app.DField.ValueChangedFcn = createCallbackFcn(app, @DFieldValueChanged, true);
            app.DField.Value = 6;
            app.SwitchEscenario = uiswitch(app.LeftPanel, 'slider');
            app.SwitchEscenario.Items = {'Alligned', 'Parallel'};
            app.SwitchEscenario.ValueChangedFcn = createCallbackFcn(app, @SwitchEscenarioValueChanged, true);
            app.SwitchEscenario.Value = 'Alligned';
            app.SwitchEscenario2 = uiswitch(app.LeftPanel, 'slider');
            app.SwitchEscenario2.Items = {'Straight road', 'Curved road'};
            app.SwitchEscenario2.ValueChangedFcn = createCallbackFcn(app, @SwitchEscenario2ValueChanged, true);
            app.SwitchEscenario2.Value = 'Straight road';
            app.VehicleConfigurationLabel = uilabel(app.LeftPanel);
            app.VehicleConfigurationLabel.Text = 'Vehicle Configuration';
            app.Label = uilabel(app.LeftPanel);
            app.VehicleconfigurationButton = uibutton(app.LeftPanel, 'push');
            app.VehicleconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @VehicleconfigurationButtonPushed, true);
            app.VehicleconfigurationButton.Text = 'Vehicle configuration';
            app.dminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dminEditField.Limits = [0 Inf];
            app.dminEditField.ValueDisplayFormat = '%11.4g [m]';
            app.dminEditField.ValueChangedFcn = createCallbackFcn(app, @dminEditFieldValueChanged, true);
            app.dminEditField.Value = 6;
            app.dminEditFieldLabel_7 = uilabel(app.LeftPanel);
            app.dminEditFieldLabel_7.Text = 'dmin';
            app.dmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.dmaxEditFieldLabel.Text = 'dmax';
            app.dmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dmaxEditField.Limits = [1 Inf];
            app.dmaxEditField.ValueDisplayFormat = '%11.4g [m]';
            app.dmaxEditField.ValueChangedFcn = createCallbackFcn(app, @dmaxEditFieldValueChanged, true);
            app.dmaxEditField.Value = 50;
            app.stepsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.stepsizeEditFieldLabel.Text = 'step size';
            app.stepsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.stepsizeEditField.Limits = [0 10];
            app.stepsizeEditField.ValueDisplayFormat = '%11.4g [m]';
            app.stepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @stepsizeEditFieldValueChanged, true);
            app.stepsizeEditField.Value = 0.1;
            app.SimulationConfigurationLabel = uilabel(app.LeftPanel);
            app.SimulationConfigurationLabel.Text = 'Simulation Configuration';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ReceiverandnoiseconfigurationLabel = uilabel(app.CenterPanel);
            app.ReceiverandnoiseconfigurationLabel.Text = 'Receiver and noise configuration';
            app.HeadlampsConfigurationHLLabel = uilabel(app.CenterPanel);
            app.HeadlampsConfigurationHLLabel.Text = 'Headlamps Configuration (HL)';
            app.FilasSpinner = uispinner(app.CenterPanel);
            app.FilasSpinner.Limits = [1 10];
            app.FilasSpinner.RoundFractionalValues = 'on';
            app.FilasSpinner.ValueDisplayFormat = '%11.4g ';
            app.FilasSpinner.ValueChangedFcn = createCallbackFcn(app, @FilasSpinnerValueChanged2, true);
            app.FilasSpinner.Value = 1;
            app.FilasSpinnerLabel = uilabel(app.CenterPanel);
            app.FilasSpinnerLabel.Text = 'Rows';
            app.ColumnasSpinner = uispinner(app.CenterPanel);
            app.ColumnasSpinner.Limits = [1 10];
            app.ColumnasSpinner.RoundFractionalValues = 'on';
            app.ColumnasSpinner.ValueDisplayFormat = '%11.4g ';
            app.ColumnasSpinner.ValueChangedFcn = createCallbackFcn(app, @ColumnasSpinnerValueChanged2, true);
            app.ColumnasSpinner.Value = 1;
            app.ColumnasSpinnerLabel = uilabel(app.CenterPanel);
            app.ColumnasSpinnerLabel.Text = 'Columns';
            app.SepHorizontSpinner = uispinner(app.CenterPanel);
            app.SepHorizontSpinner.Step = 0.001;
            app.SepHorizontSpinner.Limits = [0.001 0.2];
            app.SepHorizontSpinner.ValueDisplayFormat = '%11.4g [m]';
            app.SepHorizontSpinner.Value = 0.01;
            app.SepHorizontSpinnerLabel = uilabel(app.CenterPanel);
            app.SepHorizontSpinnerLabel.Text = 'Horizontal sep.';
            app.SepverticalSpinnerLabel = uilabel(app.CenterPanel);
            app.SepverticalSpinnerLabel.Text = 'Vertical sep.';
            app.SepverticalSpinner = uispinner(app.CenterPanel);
            app.SepverticalSpinner.Step = 0.001;
            app.SepverticalSpinner.Limits = [0.001 0.2];
            app.SepverticalSpinner.ValueDisplayFormat = '%11.4g [m]';
            app.SepverticalSpinner.ValueChangedFcn = createCallbackFcn(app, @SepverticalSpinnerValueChanged, true);
            app.SepverticalSpinner.Value = 0.01;
            app.dminEditFieldLabel_3 = uilabel(app.CenterPanel);
            app.dminEditFieldLabel_3.Text = 'Total power';
            app.powerField = uieditfield(app.CenterPanel, 'numeric');
            app.powerField.Limits = [0 Inf];
            app.powerField.ValueDisplayFormat = '%11.4g [W]';
            app.powerField.ValueChangedFcn = createCallbackFcn(app, @powerFieldValueChanged, true);
            app.powerField.Value = 1;
            app.dmaxEditFieldLabel_3 = uilabel(app.CenterPanel);
            app.dmaxEditFieldLabel_3.Text = 'HPSA';
            app.EGCSwitchLabel = uilabel(app.CenterPanel);
            app.EGCSwitchLabel.Text = 'EGC';
            app.HPSAfield = uieditfield(app.CenterPanel, 'numeric');
            app.HPSAfield.Limits = [1 Inf];
            app.HPSAfield.ValueDisplayFormat = '%11.4g [*pi/180]';
            app.HPSAfield.ValueChangedFcn = createCallbackFcn(app, @HPSAfieldValueChanged, true);
            app.HPSAfield.Value = 20;
            app.EGCSwitch = uiswitch(app.CenterPanel, 'slider');
            app.EGCSwitch.ValueChangedFcn = createCallbackFcn(app, @EGCSwitchValueChanged, true);
            app.EGCSwitch.Value = 'On';
            app.MRCSwitchLabel = uilabel(app.CenterPanel);
            app.MRCSwitchLabel.Text = 'MRC';
            app.MRCSwitch = uiswitch(app.CenterPanel, 'slider');
            app.MRCSwitch.ValueChangedFcn = createCallbackFcn(app, @MRCSwitchValueChanged, true);
            app.MRCSwitch.Value = 'On';
            app.SBSwitchLabel = uilabel(app.CenterPanel);
            app.SBSwitchLabel.Text = 'SB';
            app.SBSwitch = uiswitch(app.CenterPanel, 'slider');
            app.SBSwitch.ValueChangedFcn = createCallbackFcn(app, @SBSwitchValueChanged, true);
            app.SBSwitch.Value = 'On';
            app.ConfigurepowerofeachtxButton = uibutton(app.CenterPanel, 'push');
            app.ConfigurepowerofeachtxButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurepowerofeachtxButtonPushed, true);
            app.ConfigurepowerofeachtxButton.Text = {'Configure'; 'power of'; 'each tx'};
            app.ConfigureorientationofeachtxButton = uibutton(app.CenterPanel, 'push');
            app.ConfigureorientationofeachtxButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureorientationofeachtxButtonPushed, true);
            app.ConfigureorientationofeachtxButton.Text = {'Configure'; 'orientation of'; 'each tx'};
            app.RxconfigurationButton = uibutton(app.CenterPanel, 'push');
            app.RxconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RxconfigurationButtonPushed, true);
            app.RxconfigurationButton.Text = 'Rx configuration';
            app.CombinationmethodsLabel = uilabel(app.CenterPanel);
            app.CombinationmethodsLabel.Text = 'Combination methods';
            app.ConfigureHPSAindividuallyButton = uibutton(app.CenterPanel, 'push');
            app.ConfigureHPSAindividuallyButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureHPSAindividuallyButtonPushed, true);
            app.ConfigureHPSAindividuallyButton.Text = 'Configure HPSA individually';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SimulationOutputLabel = uilabel(app.RightPanel);
            app.SimulationOutputLabel.Text = 'Simulation Output';
            app.IlluminationdiagramButton = uibutton(app.RightPanel, 'push');
            app.IlluminationdiagramButton.ButtonPushedFcn = createCallbackFcn(app, @IlluminationdiagramButtonPushed, true);
            app.IlluminationdiagramButton.Text = 'Illumination diagram';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [0 5 10 15 20 25 30 35 40 45 50];
            app.UIAxes_3.XTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V2V_VLC_MatrixHeadlights

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end