classdef app14 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        VehiclelengthLvEditFieldLabel  matlab.ui.control.Label
        VehiclelengthLvEditField       matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        TaillampheightHBLabel          matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        VehiclelengthLvEditFieldLabel_2  matlab.ui.control.Label
        VehiclelengthLvEditFieldLabel_3  matlab.ui.control.Label
        VehiclelengthLvEditFieldLabel_4  matlab.ui.control.Label
        ChassisheightHLabel            matlab.ui.control.Label
        UpdatevehiclesButton           matlab.ui.control.Button
        VehiclelengthLvEditFieldLabel_5  matlab.ui.control.Label
        VehiclelengthLvEditField_2     matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_9                    matlab.ui.control.NumericEditField
        TaillampheightHBLabel_2        matlab.ui.control.Label
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_11                   matlab.ui.control.NumericEditField
        VehiclelengthLvEditFieldLabel_6  matlab.ui.control.Label
        VehiclelengthLvEditFieldLabel_7  matlab.ui.control.Label
        VehiclelengthLvEditFieldLabel_8  matlab.ui.control.Label
        ChassisheightHLabel_2          matlab.ui.control.Label
        UpdatevehiclesButton_2         matlab.ui.control.Button
        LEADINGVEHICLELabel            matlab.ui.control.Label
        FOLLOWINGVEHICLELabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        matrixLead
        matrixFollower
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_V1, matrixLeader, matrixFollower)
            app.CallingApp = app_V1;
            app.matrixLead = matrixLeader;
            app.matrixFollower = matrixFollower;
            app.VehiclelengthLvEditField.Value = matrixLeader(1,1);
            app.EditField_2.Value = matrixLeader(1,2);
            app.EditField_3.Value = matrixLeader(1,3);
            app.EditField_4.Value = matrixLeader(1,4);
            app.EditField_5.Value = matrixLeader(1,5);
            app.EditField_6.Value = matrixLeader(1,6);
            app.VehiclelengthLvEditField_2.Value = matrixFollower(1,1);
            app.EditField_7.Value = matrixFollower(1,2);
            app.EditField_8.Value = matrixFollower(1,3);
            app.EditField_9.Value = matrixFollower(1,4);
            app.EditField_10.Value = matrixFollower(1,5);
            app.EditField_11.Value = matrixFollower(1,6);

        end

        % Value changed function: VehiclelengthLvEditField
        function VehiclelengthLvEditFieldValueChanged(app, event)
            value = app.VehiclelengthLvEditField.Value;
            app.matrixLead(1,1) = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.matrixLead(1,2) = value;

        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.matrixLead(1,3) = value;

        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.matrixLead(1,4) = value;

        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.matrixLead(1,5) = value;

        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.matrixLead(1,6) = value;

        end

        % Button pushed function: UpdatevehiclesButton
        function UpdatevehiclesButtonPushed(app, event)
            
            updateVehicleParameters(app.CallingApp,app.matrixLead,app.matrixFollower)
            delete(app)
        end

        % Value changed function: VehiclelengthLvEditField_2
        function VehiclelengthLvEditField_2ValueChanged(app, event)
            value = app.VehiclelengthLvEditField_2.Value;
            app.matrixFollower(1,1) = value;

        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.matrixFollower(1,2) = value;

        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.matrixFollower(1,3) = value;

        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            app.matrixFollower(1,4) = value;

        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.matrixFollower(1,5) = value;

        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.matrixFollower(1,6) = value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'app_measurements.png';
            app.VehiclelengthLvEditFieldLabel = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel.Text = 'Vehicle length (Lv)';
            app.VehiclelengthLvEditField = uieditfield(app.UIFigure, 'numeric');
            app.VehiclelengthLvEditField.Limits = [0 Inf];
            app.VehiclelengthLvEditField.ValueDisplayFormat = '%11.4g   [m]';
            app.VehiclelengthLvEditField.ValueChangedFcn = createCallbackFcn(app, @VehiclelengthLvEditFieldValueChanged, true);
            app.VehiclelengthLvEditField.Value = 4.6;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1.3;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1.1;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 0.5;
            app.TaillampheightHBLabel = uilabel(app.UIFigure);
            app.TaillampheightHBLabel.Text = 'Tail lamp height (HB)';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 0.5;
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 0.5;
            app.VehiclelengthLvEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_2.Text = 'Headlight separation (DF)';
            app.VehiclelengthLvEditFieldLabel_3 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_3.Text = 'Tail lamp separation (DB)';
            app.VehiclelengthLvEditFieldLabel_4 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_4.Text = 'Headlight height (HF)';
            app.ChassisheightHLabel = uilabel(app.UIFigure);
            app.ChassisheightHLabel.Text = 'Chassis height (H)';
            app.UpdatevehiclesButton = uibutton(app.UIFigure, 'push');
            app.UpdatevehiclesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatevehiclesButtonPushed, true);
            app.UpdatevehiclesButton.Text = 'Update vehicles';
            app.VehiclelengthLvEditFieldLabel_5 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_5.Text = 'Vehicle length (Lv)';
            app.VehiclelengthLvEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VehiclelengthLvEditField_2.Limits = [0 Inf];
            app.VehiclelengthLvEditField_2.ValueDisplayFormat = '%11.4g   [m]';
            app.VehiclelengthLvEditField_2.ValueChangedFcn = createCallbackFcn(app, @VehiclelengthLvEditField_2ValueChanged, true);
            app.VehiclelengthLvEditField_2.Value = 4.6;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 1.3;
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 1.1;
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 0.5;
            app.TaillampheightHBLabel_2 = uilabel(app.UIFigure);
            app.TaillampheightHBLabel_2.Text = 'Tail lamp height (HB)';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Limits = [0 Inf];
            app.EditField_10.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Value = 0.5;
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.ValueDisplayFormat = '%11.4g   [m]';
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 0.5;
            app.VehiclelengthLvEditFieldLabel_6 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_6.Text = 'Headlight separation (DF)';
            app.VehiclelengthLvEditFieldLabel_7 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_7.Text = 'Tail lamp separation (DB)';
            app.VehiclelengthLvEditFieldLabel_8 = uilabel(app.UIFigure);
            app.VehiclelengthLvEditFieldLabel_8.Text = 'Headlight height (HF)';
            app.ChassisheightHLabel_2 = uilabel(app.UIFigure);
            app.ChassisheightHLabel_2.Text = 'Chassis height (H)';
            app.UpdatevehiclesButton_2 = uibutton(app.UIFigure, 'push');
            app.UpdatevehiclesButton_2.Text = 'Update vehicles';
            app.LEADINGVEHICLELabel = uilabel(app.UIFigure);
            app.LEADINGVEHICLELabel.Text = 'LEADING VEHICLE';
            app.FOLLOWINGVEHICLELabel = uilabel(app.UIFigure);
            app.FOLLOWINGVEHICLELabel.Text = 'FOLLOWING VEHICLE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app14(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end