classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MATRIXPOWERDISTRIBUTIONLabel  matlab.ui.control.Label
        UITableDex                    matlab.ui.control.Table
        UpdatepowerButton             matlab.ui.control.Button
        UITableIzq                    matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        LeftheadlightLabel            matlab.ui.control.Label
        RightheadlightLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        boolPotencia
        matrizDerecha
        matrizIzquierda
        filas
        columnas
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_V1, matPot, boolPotencia, filas, columnas)
            app.CallingApp = app_V1;
            long = size(matPot,1);
            app.filas = filas;
            app.columnas = columnas;
            
            matrixRight = matPot(1:long/2,1);
            matrixLeft = matPot(long/2+1:long,1);
            
            app.boolPotencia = true;
            
            dex = 1;
            izq = 1;
            app.matrizDerecha = ones(filas,columnas);
            app.matrizIzquierda = ones(filas,columnas);

            for i = 1:filas
                for j = 1:columnas
                    app.matrizDerecha(i,j) = matrixRight(dex,1);
                    app.matrizIzquierda(i,columnas-j+1) = matrixLeft(izq,1);
                    dex = dex+1;
                    izq = izq+1;
                end
            end
%             
%             for i = 1:filas
%                 for j = 1:columnas
%                     app.matrizIzquierda(i,columnas-j) = matrizLeft(dex,1);
%                     de
%                 end
%             end
            app.UITableDex.Data = app.matrizDerecha;
            app.UITableIzq.Data = app.matrizIzquierda;
        message = sprintf("Position of each headlight and transmitter is respective to the image\n"+ ...
            "Each position represents the power of each LED emitter in W\n");
        uialert(app.UIFigure,message,"INFO",'Icon',"info");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: UpdatepowerButton
        function UpdatepowerButtonPushed(app, event)
            
            DerechaTX = ones(app.columnas*app.filas,1);
            IzquierdaTX = ones(app.filas*app.columnas,1);
                x = 1;
                fprintf("derechaTX :");
                disp(DerechaTX);
                disp(IzquierdaTX);
                
            for i = 1 : app.filas
                for j = 1 : app.columnas
                    DerechaTX(x,1)=app.UITableDex.Data(i,j);
                    IzquierdaTX(x,1)=app.UITableIzq.Data(i,app.columnas-j+1);
                    x = x+1;
                end
            end
            matrizWrite = [DerechaTX;IzquierdaTX];
            fprintf("matrizWrite\r\n");
            disp(matrizWrite);
            updateMatPot(app.CallingApp,matrizWrite,app.boolPotencia)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MATRIXPOWERDISTRIBUTIONLabel = uilabel(app.UIFigure);
            app.MATRIXPOWERDISTRIBUTIONLabel.Text = 'MATRIX POWER DISTRIBUTION';
            app.UITableDex = uitable(app.UIFigure);
            app.UITableDex.ColumnName = '';
            app.UITableDex.RowName = {};
            app.UITableDex.ColumnEditable = true;
            app.UpdatepowerButton = uibutton(app.UIFigure, 'push');
            app.UpdatepowerButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatepowerButtonPushed, true);
            app.UpdatepowerButton.Text = 'Update power';
            app.UITableIzq = uitable(app.UIFigure);
            app.UITableIzq.ColumnName = '';
            app.UITableIzq.RowName = {};
            app.UITableIzq.ColumnEditable = true;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Brillo_faros.png';
            app.LeftheadlightLabel = uilabel(app.UIFigure);
            app.LeftheadlightLabel.Text = 'Left headlight';
            app.RightheadlightLabel = uilabel(app.UIFigure);
            app.RightheadlightLabel.Text = 'Right headlight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end