classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MATRIXORIENTATIONDISTRIBUTIONLabel  matlab.ui.control.Label
        UITableDex               matlab.ui.control.Table
        UpdateorientationButton  matlab.ui.control.Button
        UITableIzq               matlab.ui.control.Table
        Image                    matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        LeftheadlightLabel       matlab.ui.control.Label
        RightheadlightLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        matrizThe
        boolOrientacion
        matrizDerecha
        matrizIzquierda
        filas
        columnas
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_V1, matrizTheta, boolO, filas, columnas)
            
            
            app.matrizThe = matrizTheta;
%             fprintf("app.matrizThe \r\n");
%             disp(app.matrizThe);
            app.boolOrientacion = boolO;
            app.filas = filas;
            app.columnas = columnas;
            
            app.CallingApp = app_V1;
            long = size(app.matrizThe,1);

            matrixRightRad = 2*atan(app.matrizThe(1:long/2,2)./app.matrizThe(1:long/2,1));
            matrixLeftRad = 2*atan(app.matrizThe(long/2+1:long,2)./app.matrizThe(long/2+1:long,1));            
            matrixRight = matrixRightRad.*180/(2*pi);
            matrixLeft = matrixLeftRad.*180/(2*pi);
            
            app.boolOrientacion = true;
            
            dex = 1;
            izq = 1; % filas x columnas
            app.matrizDerecha = ones(filas,columnas);
            app.matrizIzquierda = ones(filas,columnas);

            for i = 1:filas
                for j = 1:columnas
                    app.matrizDerecha(i,j) = matrixRight(dex,1);
                    app.matrizIzquierda(i,columnas-j+1) = matrixLeft(izq,1);%app.matrizIzquierda(i,columnas-j+1) = matrixLeft(izq,1);
                    dex = dex+1;
                    izq = izq+1;
                end
            end
            app.UITableDex.Data = app.matrizDerecha;
            app.UITableIzq.Data = app.matrizIzquierda;
        message = sprintf("Position of each headlight and transmitter is respective to the image\n"+ ...
            "Each position in degrees");
        uialert(app.UIFigure,message,"INFO",'Icon',"info");
        end

        % Button pushed function: UpdateorientationButton
        function UpdateorientationButtonPushed(app, event)
%             fprintf("app.UITableDex.Data: \r\n");
%             
%             for i = 1 : app.filas
%                 for j = 1 : app.columnas
%                         fprintf("%6.2f",app.UITableDex.Data(i,j));
%                 end
%             end
%             
%             fprintf("app.UITableIzq.Data: \r\n");
%             for i = 1 : app.filas
%                 for j = 1 : app.columnas
%                         fprintf("%6.2f",app.UITableIzq.Data(i,j));
%                 end
%             end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % %             


            DerechaTX = ones(app.columnas*app.filas,1);
            IzquierdaTX = ones(app.filas*app.columnas,1);
            x = 1;
                
% % %             for i = 1 : app.filas
% % %                 for j = 1 : app.columnas
% % %                     if(app.UITableDex.Data(i,j) >= 0)
% % %                     DerechaTX(x,1)=cos((pi/360).*app.UITableDex.Data(i,j));
% % %                     DerechaTX(x,2)=sin((pi/360).*app.UITableDex.Data(i,j));
% % % 
% % %                     fprintf("if 1 \r\n");
% % %                     elseif(app.UITableDex.Data(i,j) < 0)
% % %                     DerechaTX(x,1)=cos((pi/360).*app.UITableDex.Data(i,j));
% % %                     DerechaTX(x,2)=sin((pi/360).*app.UITableDex.Data(i,j));
% % %                     fprintf("if 2 \r\n");
% % % 
% % %                     end
% % %                     
% % %                     if(app.UITableIzq.Data(i,j) >= 0)
% % %                     IzquierdaTX(x,1)=cos((pi/360).*app.UITableIzq.Data(i,app.columnas-j+1));
% % %                     IzquierdaTX(x,2)=sin((pi/360).*app.UITableIzq.Data(i,app.columnas-j+1));
% % %                     fprintf("if 3 \r\n");
% % %                     elseif(app.UITableIzq.Data(i,j) < 0)
% % %                     IzquierdaTX(x,1)=cos((pi/360).*app.UITableIzq.Data(i,app.columnas-j+1));
% % %                     IzquierdaTX(x,2)=sin((pi/360).*app.UITableIzq.Data(i,app.columnas-j+1));
% % %                     fprintf("if 4 \r\n");
% % %                     end
% % %                     x = x+1;
% % %                 end
% % %             end
          for i = 1 : app.filas
                for j = 1 : app.columnas

                    DerechaTX(x,1)=cos((pi/180).*app.UITableDex.Data(i,j));
                    DerechaTX(x,2)=sin((pi/180).*app.UITableDex.Data(i,j));

                    
                    IzquierdaTX(x,1)=cos((pi/180).*app.UITableIzq.Data(i,app.columnas-j+1));
                    IzquierdaTX(x,2)=sin((pi/180).*app.UITableIzq.Data(i,app.columnas-j+1));
                    
                    
                    x = x+1;
                end
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            matrizWrite = [DerechaTX;IzquierdaTX];
%             fprintf("matrizOrientacion:\r\n");
%             disp(matrizWrite);
            updateMatOrientacion(app.CallingApp,matrizWrite,app.boolOrientacion);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MATRIXORIENTATIONDISTRIBUTIONLabel = uilabel(app.UIFigure);
            app.MATRIXORIENTATIONDISTRIBUTIONLabel.Text = 'MATRIX ORIENTATION DISTRIBUTION';
            app.UITableDex = uitable(app.UIFigure);
            app.UITableDex.ColumnName = '';
            app.UITableDex.RowName = {};
            app.UITableDex.ColumnEditable = true;
            app.UpdateorientationButton = uibutton(app.UIFigure, 'push');
            app.UpdateorientationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateorientationButtonPushed, true);
            app.UpdateorientationButton.Text = 'Update orientation';
            app.UITableIzq = uitable(app.UIFigure);
            app.UITableIzq.ColumnName = '';
            app.UITableIzq.RowName = {};
            app.UITableIzq.ColumnEditable = true;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screen Shot 2021-07-25 at 11.14.48.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'Screen Shot 2021-07-25 at 11.16.00.png';
            app.LeftheadlightLabel = uilabel(app.UIFigure);
            app.LeftheadlightLabel.Text = 'Left headlight';
            app.RightheadlightLabel = uilabel(app.UIFigure);
            app.RightheadlightLabel.Text = 'Right headlight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end