classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        ReceptorparametersLabel        matlab.ui.control.Label
        NoiseparametersLabel           matlab.ui.control.Label
        DetectionareaAdEditFieldLabel  matlab.ui.control.Label
        DetectionareaAdEditField       matlab.ui.control.NumericEditField
        OpticalgaingEditFieldLabel     matlab.ui.control.Label
        OpticalgaingEditField          matlab.ui.control.NumericEditField
        FOVEditFieldLabel              matlab.ui.control.Label
        FOVEditField                   matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        DirectsunlightIbg2EditFieldLabel  matlab.ui.control.Label
        DirectsunlightIbg2EditField    matlab.ui.control.NumericEditField
        BandwidthBLabel                matlab.ui.control.Label
        IndirectsunlightIbg1Label      matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
    end

   
    properties (Access = public)
        CallingApp
        matrixRx
        matrixNoise
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_V1, matrixRX, matrixNoise)
            app.CallingApp = app_V1;
            app.DetectionareaAdEditField.Value = matrixRX(1,1);
            app.OpticalgaingEditField.Value = matrixRX(1,2);
            app.FOVEditField.Value = matrixRX(1,3);
            app.EditField_4.Value = matrixNoise(1,1);
            app.EditField_5.Value = matrixNoise(1,2);
            app.DirectsunlightIbg2EditField.Value = matrixNoise(1,3);
        end

        % Value changed function: DetectionareaAdEditField
        function DetectionareaAdEditFieldValueChanged(app, event)
            value = app.DetectionareaAdEditField.Value;
            app.matrixRx(1,1) = value;
        end

        % Value changed function: OpticalgaingEditField
        function OpticalgaingEditFieldValueChanged(app, event)
            value = app.OpticalgaingEditField.Value;
            app.matrixRx(1,2) = value;
        end

        % Value changed function: FOVEditField
        function FOVEditFieldValueChanged(app, event)
            value = app.FOVEditField.Value;
            app.matrixRx(1,3) = value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.matrixNoise(1,1) = value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.matrixNoise(1,2) = value;
        end

        % Value changed function: DirectsunlightIbg2EditField
        function DirectsunlightIbg2EditFieldValueChanged(app, event)
            value = app.DirectsunlightIbg2EditField.Value;
            app.matrixNoise(1,3) = value;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.matrixRx = [app.DetectionareaAdEditField.Value,app.OpticalgaingEditField.Value,app.FOVEditField.Value];
            app.matrixNoise = [app.EditField_4.Value,app.EditField_5.Value,app.DirectsunlightIbg2EditField.Value];
            disp(app.matrixRx);
            disp(app.matrixNoise);
            
            updateRxNoise(app.CallingApp,app.matrixRx,app.matrixNoise)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screen Shot 2021-07-31 at 23.42.07.png';
            app.ReceptorparametersLabel = uilabel(app.UIFigure);
            app.ReceptorparametersLabel.Text = 'Receptor parameters';
            app.NoiseparametersLabel = uilabel(app.UIFigure);
            app.NoiseparametersLabel.Text = 'Noise parameters';
            app.DetectionareaAdEditFieldLabel = uilabel(app.UIFigure);
            app.DetectionareaAdEditFieldLabel.Text = 'Detection area (Ad)';
            app.DetectionareaAdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DetectionareaAdEditField.Limits = [0 Inf];
            app.DetectionareaAdEditField.ValueDisplayFormat = '%11.4g [m^2]';
            app.DetectionareaAdEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionareaAdEditFieldValueChanged, true);
            app.DetectionareaAdEditField.Value = 5e-05;
            app.OpticalgaingEditFieldLabel = uilabel(app.UIFigure);
            app.OpticalgaingEditFieldLabel.Text = 'Optical gain (g)';
            app.OpticalgaingEditField = uieditfield(app.UIFigure, 'numeric');
            app.OpticalgaingEditField.Limits = [0 Inf];
            app.OpticalgaingEditField.ValueDisplayFormat = '%11.4g [V/V]';
            app.OpticalgaingEditField.ValueChangedFcn = createCallbackFcn(app, @OpticalgaingEditFieldValueChanged, true);
            app.OpticalgaingEditField.Value = 1;
            app.FOVEditFieldLabel = uilabel(app.UIFigure);
            app.FOVEditFieldLabel.Text = 'FOV';
            app.FOVEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVEditField.Limits = [0 360];
            app.FOVEditField.ValueDisplayFormat = '%11.4g [pi/180]';
            app.FOVEditField.ValueChangedFcn = createCallbackFcn(app, @FOVEditFieldValueChanged, true);
            app.FOVEditField.Value = 55;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%11.4g [Hz]';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 5000000;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueDisplayFormat = '%11.4g [uA]';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 740;
            app.DirectsunlightIbg2EditFieldLabel = uilabel(app.UIFigure);
            app.DirectsunlightIbg2EditFieldLabel.Text = 'Direct sunlight (Ibg2)';
            app.DirectsunlightIbg2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DirectsunlightIbg2EditField.Limits = [0 Inf];
            app.DirectsunlightIbg2EditField.ValueDisplayFormat = '%11.4g [uA]';
            app.DirectsunlightIbg2EditField.ValueChangedFcn = createCallbackFcn(app, @DirectsunlightIbg2EditFieldValueChanged, true);
            app.DirectsunlightIbg2EditField.Value = 5100;
            app.BandwidthBLabel = uilabel(app.UIFigure);
            app.BandwidthBLabel.Text = 'Bandwidth (B)';
            app.IndirectsunlightIbg1Label = uilabel(app.UIFigure);
            app.IndirectsunlightIbg1Label.Text = 'Indirect sunlight (Ibg1)';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end