classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LeftHeadlightButton   matlab.ui.control.StateButton
        RightHeadlightButton  matlab.ui.control.StateButton
        UIAxesRight           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % Description
        DaTX
        DaRX
        LeftButton
        RightButton
        DaTXoriginal
        filas
        columnas
    end
    
    methods (Access = private)
        
        function funcPrincipal(app,filas,columnas)
            pesosEGC=[0.5 0.5]; % pesos EGC
            Hright = []; Hleft = [];
            Hegc = []; HH_total = []; HH_right = []; HH_left = []; IN2 = []; IN2egc = [];
            H1 = []; H2 = []; H3 = []; H4 = [];
            long = size(app.DaTX,1);
            
            if app.RightButton == 1
                app.DaTX(1:long/2,8) = 0;
            elseif app.RightButton == 0
                app.DaTX(1:long/2,8) = app.DaTXoriginal(1:long/2,8);
            end
            if app.LeftButton == 1
                app.DaTX(long/2+1:long,8) = 0;
            elseif app.LeftButton == 0
                app.DaTX(long/2+1:long,8) = app.DaTXoriginal(long/2+1:long,8);
            end            
            
for i = 1 : 1 : 100
    for j = 1 : 1 : 100
        app.DaRX(:,1) = i;
        app.DaRX(:,2) = j;
        app.DaRX(:,4) = -1;
        app.DaRX(:,5) = 0;
        
        [Hdc,tp]=CalculaParametros(app.DaTX,app.DaRX);            
         
        Size = filas*columnas;
        
        H1 = Hdc(1,1:Size).*app.DaTX(1:Size,8);
        H2 = Hdc(1,Size+1:2*Size).*app.DaTX(Size+1:2*Size,8);
        H3 = Hdc(2,1:Size).*app.DaTX(1:Size,8);
        H4 = Hdc(2,Size+1:2*Size).*app.DaTX(Size+1:2*Size,8);
        
        H_dc = (1/(Size)).*[sum(H1,'all') sum(H2,'all'); sum(H3,'all') sum(H4,'all')];

%         Hegc=[Hegc sum(H_dc'*pesosEGC.')];
        
%        HH(i,j) = sum(H_dc'*app.DaTX(:,8);
     
     B=5*10^6;       % ancho de banda
     qe=1.6*10^-19;  % carga del electron
     Ibg1=740*10^-6;   % Sol indirecto esto debería ser variable
     Ibg2=5100*10^-6;   % Sol directo esto debería ser variable
     Ibg= Ibg1*(0.5/0.8);%la corrijo por diferentes areas 0.5/0.8    
     I2=0.562;
     nshot=2*qe*Ibg*I2*B; % ruido shot
     nint=0;              % ruido interno
     in=nshot+nint;       % ruido total
     IN2=[IN2 in];
%%%%%
        %int = sum(Hdc);
        %IN2egc=[IN2egc in*sum(pesosEGC.^2)];

         NN(i,j) = sum(IN2);
         HH(i,j) = sum(H_dc'*pesosEGC.');

            
    end
end

        SNR = 10*log10(abs(HH)./abs(NN));
        %SNR = (abs(HH));

         %respons=0.5;
         %SNRegc=((respons*HH).^2)./IN2egc(1,1);
         %SNR = 10*log10(SNRegc);

            X = -49:1:50;
            Y = 1:1:100;

            contourf(app.UIAxesRight,SNR,20);
            colormap(app.UIAxesRight, "hot");
            %app.UIAxesRight.XGrid = 'on';
            %app.UIAxesRight.YGrid = 'on';
            %app.UIAxesRight.GridColor = 'c';
            app.UIAxesRight.Title.String = "Illumination diagram of headlights";
            app.UIAxesRight.XLabel.String = "Distancia Y[m]";
            app.UIAxesRight.YLabel.String = "Distancia X[m]";
            c = colorbar(app.UIAxesRight,'hot','eastoutside');
            c.Label.String = "SNR(dB)";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_V1, DaTX, DaRX, filas, columnas)
            app.CallingApp = app_V1;
            app.DaTX = DaTX;
            app.DaTX(:,2) = DaTX(:,2) + 50;

            app.DaTXoriginal = DaTX;
            app.DaRX = DaRX;
            app.filas = filas;
            app.columnas = columnas;
            funcPrincipal(app,app.filas,app.columnas);
            
        end

        % Value changed function: LeftHeadlightButton
        function LeftHeadlightButtonValueChanged(app, event)
            value = app.LeftHeadlightButton.Value;
            app.LeftButton = value;
            fprintf("LeftButton\r\n");
            funcPrincipal(app,app.filas,app.columnas);

        end

        % Value changed function: RightHeadlightButton
        function RightHeadlightButtonValueChanged(app, event)
            value = app.RightHeadlightButton.Value;
            app.RightButton = value;
            fprintf("RightButton\r\n");
            funcPrincipal(app,app.filas,app.columnas);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftHeadlightButton = uibutton(app.UIFigure, 'state');
            app.LeftHeadlightButton.ValueChangedFcn = createCallbackFcn(app, @LeftHeadlightButtonValueChanged, true);
            app.LeftHeadlightButton.Text = 'Left Headlight';
            app.RightHeadlightButton = uibutton(app.UIFigure, 'state');
            app.RightHeadlightButton.ValueChangedFcn = createCallbackFcn(app, @RightHeadlightButtonValueChanged, true);
            app.RightHeadlightButton.Text = 'Right Headlight';
            app.UIAxesRight = uiaxes(app.UIFigure);
            title(app.UIAxesRight, 'Title')
            xlabel(app.UIAxesRight, 'X')
            ylabel(app.UIAxesRight, 'Y')
            zlabel(app.UIAxesRight, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end